classdef annotate_fluoro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        HoverForLabelsCheckBox        matlab.ui.control.CheckBox
        HemiEcogDropDown              matlab.ui.control.DropDown
        HemiDropDown_2Label           matlab.ui.control.Label
        NumberInterpEditField         matlab.ui.control.NumericEditField
        NumberInterpEditFieldLabel    matlab.ui.control.Label
        LoadFluoroButton              matlab.ui.control.Button
        SubjectEditField              matlab.ui.control.EditField
        SubjectEditFieldLabel         matlab.ui.control.Label
        StartatEditField              matlab.ui.control.NumericEditField
        StartatEditFieldLabel         matlab.ui.control.Label
        ContactsPrefixEditField       matlab.ui.control.EditField
        ContactsPrefixEditFieldLabel  matlab.ui.control.Label
        MarkElectrodeSplineButton     matlab.ui.control.Button
        MarkLandmarkButton            matlab.ui.control.Button
        LandmarkNameDropDown          matlab.ui.control.DropDown
        LandmarkNameDropDownLabel     matlab.ui.control.Label
        HemiDropDown                  matlab.ui.control.DropDown
        HemiDropDownLabel             matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        MessageBoxTextArea            matlab.ui.control.TextArea
        MessageBoxTextAreaLabel       matlab.ui.control.Label
        SaveLandmarksButton           matlab.ui.control.Button
        LoadLandmarksButton           matlab.ui.control.Button
        DeleteRowsButton              matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Paths = struct() % stores all paths for the GUI
        
    end
    
    methods (Access = private)
        
        function [] = AppendToTable(app, s)
            T = app.UITable.Data; 
            T = bml_annot_rowbind(T, struct2table(s)); 
%             T = sortrows(T, "name", "descend"); 
            idxs_top = ismember(T.name, app.LandmarkNameDropDown.Items);
            T = [T(idxs_top, :); T(~idxs_top, :)];

            set(T.hP, 'LabelVisible', 'hover');

            app.UITable.Data = T; 
        end

        function [] = UserMessage(app, msg)
            app.MessageBoxTextArea.Value = app.MessageBoxTextArea.Value + "\n>>" + msg; 
        end

        
        function app = PathsFactory(app)
%             paths = []; 
%             paths.FluoroImage = DynPath("Y:\DBS\derivatives\sub-{subject_id}\ecogloc\sub-{subject_id}_ses-intraop_fluoro-L.tiff", "subject_id", app.SubjectEditField.Value); 
% %             paths.FluoroLandmarksTable = DynPath("Y:\DBS\derivatives\sub-{subject_id}\ecogloc\sub-{subject_id}_fluoro-landmarks.tsv", "subject_id", app.SubjectEditField.Value);
%             
%             paths = []; 
%             paths.FluoroImage = "Y:\DBS\derivatives\sub-"+app.SubjectEditField.Value+"\ecogloc\sub-"+app.SubjectEditField.Value+"_ses-intraop_fluoro-L.tiff";
%             paths.FluoroLandmarksTable = "Y:\DBS\derivatives\sub-"+app.SubjectEditField.Value+"\ecogloc\sub-"+app.SubjectEditField.Value+"_fluoro-landmarks.tsv"; 


            if ~isfield(app.Paths, 'BaseDir')
               app.Paths.BaseDir = uigetdir([], 'Select root directory for DBS data');
            end
            
            sub_id = app.SubjectEditField.Value;
            sub_dir = fullfile(app.Paths.BaseDir, "sub-" + sub_id, "ecogloc");
            
            app.Paths.FluoroImage = fullfile(sub_dir, "sub-" + sub_id + "_ses-intraop_fluoro-L.tiff");
            app.Paths.FluoroLandmarksTable = fullfile(sub_dir, "sub-" + sub_id + "_fluoro-landmarks.tsv");
        end

        function data = safeReadTable(app, fieldName, varargin)
            % Attempts to read from app.Paths.(fieldName)
            % Falls back to uigetfile if that fails
            path = app.Paths.(fieldName);

            try
                if isfile(path)
                    opts = detectImportOptions(path, 'FileType', 'text');  % dummy path just to get options
                    opts = setvartype(opts, {'hemi', 'name'}, "string");
                    data = readtable(path, opts);
                    app.UserMessage("Loaded file: " + path);
                else
                    error("File does not exist.");
                end
            catch
                app.UserMessage("Default path failed: " + path);
                [file, folder] = uigetfile({'*.tsv;*.csv;*.txt'}, 'Select file to read');
                if isequal(file, 0)
                    app.UserMessage("File read cancelled.");
                    data = []; return;
                end
                opts = detectImportOptions(fullfile(folder, file), 'FileType', 'text');  % dummy path just to get options
                opts = setvartype(opts, {'hemi', 'name'}, "string");
                data = readtable(fullfile(folder, file), opts);
            end
        end

        function success = safeWriteTable(app, T, fieldName, varargin)
            % Attempts to write to app.Paths.(fieldName)
            % Falls back to uiputfile if that fails
            path = app.Paths.(fieldName);
            try
                writetable(T, path, varargin{:});
                app.UserMessage("Saved file: " + path);
                success = true;
            catch
                app.UserMessage("Default path failed: " + path);
                [file, folder] = uiputfile({'*.tsv'}, 'Save file as');
                if isequal(file, 0)
                    app.UserMessage("File save cancelled.");
                    success = false; return;
                end
                writetable(T, fullfile(folder, file), varargin{:});
                success = true;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%            app.Paths = PathsFactory(app); 
           app.Paths.BaseDir = uigetdir([], 'Select root directory for DBS data');
           PathsFactory(app);
        end

        % Button pushed function: LoadFluoroButton
        function LoadFluoroButtonPushed(app, event)
            PathsFactory(app); % update all paths
            
            if exist(app.Paths.FluoroImage, 'file')
            I_full = imread(app.Paths.FluoroImage);
            else
                [file, path] = uigetfile({'*.tif;*.tiff;*.png;*.jpg'}, 'Select fluoro image');
                if isequal(file,0)
                    UserMessage(app, 'Image load cancelled');
                    return
                end
                I_full = imread(fullfile(path, file));
            end
%             try 
%                 app.paths.FluoroImage = "Y:\DBS\derivatives\sub-"+app.SubjectEditField.Value+"\ecogloc\sub-"+app.SubjectEditField.Value+"_ses-intraop_fluoro-L.tiff";
%                 disp(app.paths.FluoroImage);
%                 I_full = imread(app.Paths.FluoroImage);
%             catch
%                 [file, path] = uigetfile({'*.png;*.jpeg;*.tif;*.tiff'}); 
%                 I_full = imread(fullfile(path,file));
%             end
            I = I_full(:, :, 3);

            image(app.UIAxes, [-1 1], [1 -1], I);
            colormap(app.UIAxes, "gray")
            axis(app.UIAxes, 'square')
            axis(app.UIAxes, 'tight')
            set(app.UIAxes, 'ydir','normal'); 

            app.UITable.Data = table();
        end

        % Button pushed function: MarkElectrodeSplineButton
        function MarkElectrodeSplineButtonPushed(app, event)
            %%
            app.UIFigure.HandleVisibility = 'on'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
    
            axes(app.UIAxes)
            UserMessage(app, 'Marking ecog strips. Click points along a single line. Press Enter when finished.')
            waitfor(msgbox('Marking ecog strips. Click points along a strip line. Press Enter when finished.'))
            
            x = []; y = []; hP_tmp = gobjects(); 
            while true
                [xtmp, ytmp, button] = ginput(1);
                if ~isempty(xtmp) && button == 1 % if left mouse click, save coordinate + plot on image
                    x(end+1)=xtmp; y(end+1)=ytmp;
                    hP_tmp(end+1) = drawpoint(app.UIAxes, 'Position', [x(end) y(end)]); hP_tmp(end).Label = "p"+string(length(x));
                elseif ~isempty(xtmp) && ismember(button, [3 8]); % else if backspace or right click key pressed, delete the last coordinate
                    x(end) = []; y(end)=[]; 
                    delete(hP_tmp(end));  hP_tmp(end) = []; 
                else  % <------ EMPTY
                    delete(hP_tmp);
                    break
                end
            end
            spcv = cscvn( [x; y]);
            hold on
            % fnplt( spcv )
            shg


            % Define the number of points for interpolation
            num_points = app.NumberInterpEditField.Value;
%             input("how many elecs?");
%             input("prefix?");


            % Get the breaks (knots) of the spline
            knots = spcv.breaks;

            % Create equally spaced points between the first and last knot
            interp_points = linspace(knots(1), knots(end), num_points);

            % Evaluate the spline at the interpolated points
            interp_values = fnval(spcv, interp_points);

            contact_number_string = string(compose('%02d', (1:num_points)-1 + app.StartatEditField.Value));

            % Extract x and y coordinates
            for ielec = 1:num_points
                s = []; 
                s.hemi = app.HemiEcogDropDown.Value; 
                s.name = string(app.ContactsPrefixEditField.Value)+ contact_number_string(ielec); 
%                 s.pos_1 = interp_values(1, ielec);  
%                 s.pos_2 = interp_values(2, ielec); 
                s.hP = drawpoint(app.UIAxes, 'Position', [interp_values(1, ielec) interp_values(2, ielec)]); s.hP.Label = s.name;
%                 set(app.UITable.Data.hP, '')
                AppendToTable(app, s); 
            end

            app.StartatEditField.Value = app.StartatEditField.Value + app.NumberInterpEditField.Value; 
        end

        % Button pushed function: MarkLandmarkButton
        function MarkLandmarkButtonPushed(app, event)
            app.UIFigure.HandleVisibility = 'on'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
    
            axes(app.UIAxes);
            UserMessage(app, 'Mark landmark: Press Enter when finished.')

            [x, y] = ginput(1); 
            x = x(1); y = y(1); 
            hold on
            
            s = []; 
            s.hemi = string(app.HemiDropDown.Value); 
            s.name = string(app.LandmarkNameDropDown.Value); 
%             s.pos_1 = x; 
%             s.pos_2 = y; 
            s.hP = drawpoint(app.UIAxes, 'Position', [x y]); s.hP.Label = s.name;
            AppendToTable(app, s)

%             idx = find(strcmp(app.LandmarkNameDropDown.Items, app.LandmarkNameDropDown.Value));
%             app.LandmarkNameDropDown.Value = app.LandmarkNameDropDown.Items(mod(idx+1, 4));

        end

        % Button pushed function: DeleteRowsButton
        function DeleteRowsButtonPushed(app, event)
            idxs = app.UITable.Selection;
            delete(app.UITable.Data.hP(idxs)); 
            app.UITable.Data(idxs, :) = []; 
            % app.UITable.Data.hP()
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            idxs = false(1, height(app.UITable.Data)); 
            idxs(app.UITable.Selection) = true;
            set(app.UITable.Data.hP(idxs), "Color", 'r', 'LabelVisible', 'on'); 
            set(app.UITable.Data.hP(~idxs), "Color", [0 0.4470 0.7410], 'LabelVisible', 'off'); 

        end

        % Button pushed function: SaveLandmarksButton
        function SaveLandmarksButtonPushed(app, event)
            T = app.UITable.Data;
            T.pos_1 = arrayfun(@(hP) hP.Position(1), T.hP);
            T.pos_2 = arrayfun(@(hP) hP.Position(2), T.hP);
            T.hP = []; 

            T = app.UITable.Data;
            T.pos_1 = arrayfun(@(hP) hP.Position(1), T.hP);
            T.pos_2 = arrayfun(@(hP) hP.Position(2), T.hP);
            T.hP = [];

            success = safeWriteTable(app, T, "FluoroLandmarksTable", 'FileType', 'text', 'Delimiter', '\t');
            if success
                msgbox("Saved successfully!");
            end
%             
%             app.paths.FluoroLandmarksTable = "Y:\DBS\derivatives\sub-"+app.SubjectEditField.Value+"\ecogloc\sub-"+app.SubjectEditField.Value+"_fluoro-landmarks.tsv"; 
%             writetable(T, app.Paths.FluoroLandmarksTable, 'FileType', 'text', 'Delimiter', '\t');
%             f = msgbox("Saved successfully!");
        end

        % Button pushed function: LoadLandmarksButton
        function LoadLandmarksButtonPushed(app, event)
%             app.Paths.FluoroLandmarksTable = "Y:\DBS\derivatives\sub-"+app.SubjectEditField.Value+"\ecogloc\sub-"+app.SubjectEditField.Value+"_ses-intraop_fluoro-L.tiff";
%             disp(app.Paths.FluoroLandmarksTable);
%             opts = detectImportOptions(app.Paths.FluoroLandmarksTable, 'FileType', 'text');
%             opts = setvartype(opts, {'hemi', 'name'}, "string");
%             T = readtable(app.Paths.FluoroLandmarksTable, opts);
             
            T = safeReadTable(app, "FluoroLandmarksTable", []);
            if isempty(T)
                return
            end

            % Extract x and y coordinates
            T.hP = gobjects(height(T), 1); 
            for irow = 1:height(T)
                s = []; 
%                 s.hemi = app.HemiEcogDropDown.Value; 
%                 s.name = string(app.ContactsPrefixEditField.Value)+ contact_number_string(ielec); 
%                 s.pos_1 = interp_values(1, ielec);  
%                 s.pos_2 = interp_values(2, ielec); 
                T.hP(irow) = drawpoint(app.UIAxes, 'Position', [T.pos_1(irow) T.pos_2(irow)]); 
                T.hP(irow).Label = T.name(irow);
            end
            T.pos_1 = []; 
            T.pos_2 = []; 
            app.UITable.Data = T; 
        end

        % Value changed function: HoverForLabelsCheckBox
        function HoverForLabelsCheckBoxValueChanged(app, event)
            value = app.HoverForLabelsCheckBox.Value;
            if value==1
            set(app.UITable.Data.hP, 'LabelVisible', 'hover'); 
            else 
            set(app.UITable.Data.hP, 'LabelVisible', 'off'); 
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {908, 908};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {215, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HemiDropDownLabel = uilabel(app.LeftPanel);
            app.HemiDropDownLabel.Text = 'Hemi';
            app.HemiDropDown = uidropdown(app.LeftPanel);
            app.HemiDropDown.Items = {'uncertain', 'left', 'right'};
            app.HemiDropDown.Value = 'uncertain';
            app.LandmarkNameDropDownLabel = uilabel(app.LeftPanel);
            app.LandmarkNameDropDownLabel.Text = 'Landmark Name';
            app.LandmarkNameDropDown = uidropdown(app.LeftPanel);
            app.LandmarkNameDropDown.Items = {'pintip_front', 'pintip_occ', 'dbslead_bottom', 'dbslead_top'};
            app.LandmarkNameDropDown.Value = 'pintip_front';
            app.MarkLandmarkButton = uibutton(app.LeftPanel, 'push');
            app.MarkLandmarkButton.ButtonPushedFcn = createCallbackFcn(app, @MarkLandmarkButtonPushed, true);
            app.MarkLandmarkButton.Text = 'Mark Landmark';
            app.MarkElectrodeSplineButton = uibutton(app.LeftPanel, 'push');
            app.MarkElectrodeSplineButton.ButtonPushedFcn = createCallbackFcn(app, @MarkElectrodeSplineButtonPushed, true);
            app.MarkElectrodeSplineButton.Text = 'Mark Electrode Spline';
            app.ContactsPrefixEditFieldLabel = uilabel(app.LeftPanel);
            app.ContactsPrefixEditFieldLabel.Text = 'Contacts Prefix';
            app.ContactsPrefixEditField = uieditfield(app.LeftPanel, 'text');
            app.ContactsPrefixEditField.Value = 'ecog_L1';
            app.StartatEditFieldLabel = uilabel(app.LeftPanel);
            app.StartatEditFieldLabel.Text = 'Start at';
            app.StartatEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartatEditField.Value = 1;
            app.SubjectEditFieldLabel = uilabel(app.LeftPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.LeftPanel, 'text');
            app.SubjectEditField.Value = 'DM1007';
            app.LoadFluoroButton = uibutton(app.LeftPanel, 'push');
            app.LoadFluoroButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFluoroButtonPushed, true);
            app.LoadFluoroButton.Text = 'Load Fluoro';
            app.NumberInterpEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberInterpEditFieldLabel.Text = 'Number Interp';
            app.NumberInterpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberInterpEditField.Value = 21;
            app.HemiDropDown_2Label = uilabel(app.LeftPanel);
            app.HemiDropDown_2Label.Text = 'Hemi';
            app.HemiEcogDropDown = uidropdown(app.LeftPanel);
            app.HemiEcogDropDown.Items = {'left', 'right'};
            app.HemiEcogDropDown.Value = 'left';
            app.HoverForLabelsCheckBox = uicheckbox(app.LeftPanel);
            app.HoverForLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoverForLabelsCheckBoxValueChanged, true);
            app.HoverForLabelsCheckBox.Text = 'Hover For Labels';
            app.HoverForLabelsCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title', 'Interpreter', 'tex')
            xlabel(app.UIAxes, 'X', 'Interpreter', 'tex')
            ylabel(app.UIAxes, 'Y', 'Interpreter', 'tex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'tex')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'hemi'; 'name'; 'hP'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.DeleteRowsButton = uibutton(app.GridLayout2, 'push');
            app.DeleteRowsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowsButtonPushed, true);
            app.DeleteRowsButton.Text = 'Delete Rows';
            app.LoadLandmarksButton = uibutton(app.GridLayout2, 'push');
            app.LoadLandmarksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLandmarksButtonPushed, true);
            app.LoadLandmarksButton.Text = 'Load Landmarks';
            app.SaveLandmarksButton = uibutton(app.GridLayout2, 'push');
            app.SaveLandmarksButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLandmarksButtonPushed, true);
            app.SaveLandmarksButton.Text = 'Save Landmarks';
            app.MessageBoxTextAreaLabel = uilabel(app.GridLayout2);
            app.MessageBoxTextAreaLabel.Text = 'Message Box';
            app.MessageBoxTextArea = uitextarea(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotate_fluoro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end